classdef robotbartenderAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartButton               matlab.ui.control.Button
        ClearButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ontherocksButton          matlab.ui.control.Button
        shakennotstirredButton    matlab.ui.control.Button
        RumandcokeButton          matlab.ui.control.Button
        q1SliderLabel             matlab.ui.control.Label
        q1Slider                  matlab.ui.control.Slider
        q2SliderLabel             matlab.ui.control.Label
        q2Slider                  matlab.ui.control.Slider
        q3SliderLabel             matlab.ui.control.Label
        q3Slider                  matlab.ui.control.Slider
        q4SliderLabel             matlab.ui.control.Label
        q4Slider                  matlab.ui.control.Slider
        q5SliderLabel             matlab.ui.control.Label
        q5Slider                  matlab.ui.control.Slider
        q6SliderLabel             matlab.ui.control.Label
        q6Slider                  matlab.ui.control.Slider
        ControltoggleButtonGroup  matlab.ui.container.ButtonGroup
        Robot1Button              matlab.ui.control.RadioButton
        Robot2Button              matlab.ui.control.RadioButton
        RickButton                matlab.ui.control.RadioButton
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        ROLLEditFieldLabel        matlab.ui.control.Label
        ROLLEditField             matlab.ui.control.NumericEditField
        PITCHEditFieldLabel       matlab.ui.control.Label
        PITCHEditField            matlab.ui.control.NumericEditField
        YAWEditFieldLabel         matlab.ui.control.Label
        YAWEditField              matlab.ui.control.NumericEditField
        goButton                  matlab.ui.control.Button
        testlightcirtainButton    matlab.ui.control.Button
        objectcolButton           matlab.ui.control.Button
        HomeviewButton            matlab.ui.control.Button
        joycontrolButtonGroup     matlab.ui.container.ButtonGroup
        NoneButton                matlab.ui.control.ToggleButton
        Robot1Button_2            matlab.ui.control.ToggleButton
        Robot2Button_2            matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        robots % Description
        objects % Description
        environment % Description
    end
    
    methods (Access = private)
        
        function results = UpdatePose(app)
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            q5 = app.q5Slider.Value;
            q6 = app.q6Slider.Value;
            
            if app.Robot1Button.Value
                pose = app.robots(1).model.fkine([q1,q2,q3,q4,q5,q6])
                
            end
            if app.Robot2Button.Value
                pose = app.robots(2).model.fkine([q1,q2,q3,q4,q5,q6])
                
            end
            if app.RickButton.Value
                pose = app.environment(2).model.base;
                
            end
            
            app.XEditField.Value = pose(1,4);
            app.YEditField.Value = pose(2,4);
            app.ZEditField.Value = pose(3,4);
            
            rpy = tr2rpy(pose);
            app.ROLLEditField.Value = rpy(1);
            app.PITCHEditField.Value = rpy(2);
            app.YAWEditField.Value = rpy(3);
            
        end
        
        function results = UpdateJoints(app)
            
        end
        
        function results = updateUI(app)
            if app.Robot1Button.Value
                qlims = app.robots(1).model.qlim
                app.q1Slider.Limits = qlims(1,:);
                app.q2Slider.Limits = qlims(2,:);
                app.q3Slider.Limits = qlims(3,:);
                app.q4Slider.Limits = qlims(4,:);
                app.q5Slider.Limits = qlims(5,:);
                app.q6Slider.Limits = qlims(6,:);
                app.q1Slider.Enable = true;
                app.q2Slider.Enable = true;
                app.q3Slider.Enable = true;
                app.q4Slider.Enable = true;
                app.q5Slider.Enable = true;
                app.q6Slider.Enable = true;
                
                pos = app.robots(1).model.getpos;
                app.q1Slider.Value = pos(1);
                app.q2Slider.Value = pos(2);
                app.q3Slider.Value = pos(3);
                app.q4Slider.Value = pos(4);
                app.q5Slider.Value = pos(5);
                app.q6Slider.Value = pos(6);
            end
            if app.Robot2Button.Value
                qlims = app.robots(2).model.qlim
                app.q1Slider.Limits = qlims(1,:);
                app.q2Slider.Limits = qlims(2,:);
                app.q3Slider.Limits = qlims(3,:);
                app.q4Slider.Limits = qlims(4,:);
                app.q5Slider.Limits = qlims(5,:);
                app.q6Slider.Limits = qlims(6,:);
                app.q1Slider.Enable = true;
                app.q2Slider.Enable = true;
                app.q3Slider.Enable = true;
                app.q4Slider.Enable = true;
                app.q5Slider.Enable = true;
                app.q6Slider.Enable = true;
                
                pos = app.robots(2).model.getpos;
                app.q1Slider.Value = pos(1);
                app.q2Slider.Value = pos(2);
                app.q3Slider.Value = pos(3);
                app.q4Slider.Value = pos(4);
                app.q5Slider.Value = pos(5);
                app.q6Slider.Value = pos(6);
            end
            if app.RickButton.Value
                
                app.q1Slider.Limits = [0,1];
                app.q2Slider.Limits = [0,1];
                app.q3Slider.Limits = [0,1];
                app.q4Slider.Limits = [0,1];
                app.q5Slider.Limits = [0,1];
                app.q6Slider.Limits = [0,1];
                app.q1Slider.Enable = false;
                app.q2Slider.Enable = false;
                app.q3Slider.Enable = false;
                app.q4Slider.Enable = false;
                app.q5Slider.Enable = false;
                app.q6Slider.Enable = false;
            end
            
            UpdatePose(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            set(0,'DefaultFigureWindowStyle','docked')
            clear
            clc      
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            [app.robots, app.objects,app.environment] = setup();
            updateUI(app);
        end

        % Button pushed function: RumandcokeButton
        function RumandcokeButtonPushed(app, event)
            stirredRumAndCoke([app.robots(1), ...
                app.robots(2)], ...
                [app.objects(8), ...
                app.objects(7), ...
                app.objects(6), ...
                app.objects(3), ...
                app.objects(5)], ...
                app.environment(3));
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            close all
            set(0,'DefaultFigureWindowStyle','docked')
            clear
            clc
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            for i = 1:size(app.robots, 2)
                app.robots(i).home;
            end
        end

        % Button pushed function: ontherocksButton
        function ontherocksButtonPushed(app, event)
            onTheRocks([app.robots(2), ...
                app.robots(1)], ...
                [app.objects(1), ...
                app.objects(3)], ...
                app.environment);
        end

        % Button pushed function: shakennotstirredButton
        function shakennotstirredButtonPushed(app, event)
            shakenNotstired([app.robots(1), ...
                app.robots(2)], ...
                [app.objects(2), ...
                app.objects(3), ...
                app.objects(6)], ...
                app.environment);
        end

        % Selection changed function: ControltoggleButtonGroup
        function ControltoggleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControltoggleButtonGroup.SelectedObject;
            updateUI(app);
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            q5 = app.q5Slider.Value;
            q6 = app.q6Slider.Value;
            q1 = value;
            if app.Robot1Button.Value
                app.robots(1).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            if app.Robot2Button.Value
                app.robots(2).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            UpdatePose(app);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            q5 = app.q5Slider.Value;
            q6 = app.q6Slider.Value;
            q2 = value;
            if app.Robot1Button.Value
                app.robots(1).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            if app.Robot2Button.Value
                app.robots(2).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            UpdatePose(app);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            q5 = app.q5Slider.Value;
            q6 = app.q6Slider.Value;
            q3 = value;
            if app.Robot1Button.Value
                app.robots(1).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            if app.Robot2Button.Value
                app.robots(2).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            UpdatePose(app);
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            value = app.q4Slider.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            q5 = app.q5Slider.Value;
            q6 = app.q6Slider.Value;
            q4 = value;
            if app.Robot1Button.Value
                app.robots(1).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            if app.Robot2Button.Value
                app.robots(2).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            UpdatePose(app);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            q5 = app.q5Slider.Value;
            q6 = app.q6Slider.Value;
            q5 = value;
            if app.Robot1Button.Value
                app.robots(1).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            if app.Robot2Button.Value
                app.robots(2).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            UpdatePose(app);
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            value = app.q6Slider.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            q4 = app.q4Slider.Value;
            q5 = app.q5Slider.Value;
            q6 = app.q6Slider.Value;
            q6 = value;
            if app.Robot1Button.Value
                app.robots(1).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            if app.Robot2Button.Value
                app.robots(2).model.animate([q1,q2,q3,q4,q5,q6]);
            end
            UpdatePose(app);
        end

        % Button pushed function: goButton
        function goButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            xr = app.ROLLEditField.Value;
            yr = app.PITCHEditField.Value;
            zr = app.YAWEditField.Value;
            
            Pose = transl(x,y,z) * rpy2tr(xr,yr,zr);
            if app.Robot1Button.Value
                q = app.robots(1).model.ikcon(Pose);
                app.robots(1).model.animate(q);
            end
            if app.Robot2Button.Value
                q = app.robots(2).model.ikcon(Pose);
                app.robots(2).model.animate(q);
            end
            if app.RickButton.Value
                q = 0;
                app.environment(3).model.base = Pose;
                app.environment(3).model.animate(q);
            end
        end

        % Button pushed function: testlightcirtainButton
        function testlightcirtainButtonPushed(app, event)
            workspace = [-3 3 -5 5 0 5];
            
            lightCurtain.X = [-0.95, 0.8];
            lightCurtain.Y = [-2.9, -2.9];
            lightCurtain.Z = [2.3, 2.3];
            
%             for lineIt = lightCurtain.Z(1):-0.1:0.5
%                 plot3(lightCurtain.X,lightCurtain.Y,[lineIt, lineIt],'--r','LineWidth',0.1);
%             end
            
            Square = Objects('Square', '1', workspace, transl([-4,-4,1.5]), 0);
            
            % Adjust view
            view(300,20);
            
            testLightCurtain(Square, lightCurtain);
        end

        % Button pushed function: objectcolButton
        function objectcolButtonPushed(app, event)
            if app.environment(3).model.base == transl(-0.6,-2,1.4)
                app.environment(3).model.base = transl(-0.6,0,1.4);
                app.environment(3).model.animate(0);
            else
                app.environment(3).model.base = transl(-0.6,-2,1.4);
                app.environment(3).model.animate(0);
            end
        end

        % Selection changed function: joycontrolButtonGroup
        function joycontrolButtonGroupSelectionChanged(app, event)
            selectedButton = app.joycontrolButtonGroup.SelectedObject;
            
            while app.Robot1Button_2.Value 
                if app.robots(1).NOJOY == false
                    app.robots(1).joggingLoop(0.01,1);
                end
            end
            while app.Robot2Button_2.Value
                if app.robots(1).NOJOY == false
                    app.robots(2).joggingLoop(0.01,1);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ontherocksButton = uibutton(app.UIFigure, 'push');
            app.ontherocksButton.ButtonPushedFcn = createCallbackFcn(app, @ontherocksButtonPushed, true);
            app.ontherocksButton.Text = 'on the rocks';
            app.shakennotstirredButton = uibutton(app.UIFigure, 'push');
            app.shakennotstirredButton.ButtonPushedFcn = createCallbackFcn(app, @shakennotstirredButtonPushed, true);
            app.shakennotstirredButton.Text = 'shaken not stirred';
            app.RumandcokeButton = uibutton(app.UIFigure, 'push');
            app.RumandcokeButton.ButtonPushedFcn = createCallbackFcn(app, @RumandcokeButtonPushed, true);
            app.RumandcokeButton.Text = 'Rum and coke';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.ControltoggleButtonGroup = uibuttongroup(app.UIFigure);
            app.ControltoggleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControltoggleButtonGroupSelectionChanged, true);
            app.ControltoggleButtonGroup.Title = 'Control toggle';
            app.Robot1Button = uiradiobutton(app.ControltoggleButtonGroup);
            app.Robot1Button.Text = 'Robot 1';
            app.Robot1Button.Value = true;
            app.Robot2Button = uiradiobutton(app.ControltoggleButtonGroup);
            app.Robot2Button.Text = 'Robot 2';
            app.RickButton = uiradiobutton(app.ControltoggleButtonGroup);
            app.RickButton.Text = 'Rick';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROLLEditFieldLabel = uilabel(app.UIFigure);
            app.ROLLEditFieldLabel.Text = 'ROLL';
            app.ROLLEditField = uieditfield(app.UIFigure, 'numeric');
            app.PITCHEditFieldLabel = uilabel(app.UIFigure);
            app.PITCHEditFieldLabel.Text = 'PITCH';
            app.PITCHEditField = uieditfield(app.UIFigure, 'numeric');
            app.YAWEditFieldLabel = uilabel(app.UIFigure);
            app.YAWEditFieldLabel.Text = 'YAW';
            app.YAWEditField = uieditfield(app.UIFigure, 'numeric');
            app.goButton = uibutton(app.UIFigure, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Text = 'go';
            app.testlightcirtainButton = uibutton(app.UIFigure, 'push');
            app.testlightcirtainButton.ButtonPushedFcn = createCallbackFcn(app, @testlightcirtainButtonPushed, true);
            app.testlightcirtainButton.Text = 'test light cirtain';
            app.objectcolButton = uibutton(app.UIFigure, 'push');
            app.objectcolButton.ButtonPushedFcn = createCallbackFcn(app, @objectcolButtonPushed, true);
            app.objectcolButton.Text = 'object col';
            app.HomeviewButton = uibutton(app.UIFigure, 'push');
            app.HomeviewButton.Text = 'Home view';
            app.joycontrolButtonGroup = uibuttongroup(app.UIFigure);
            app.joycontrolButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @joycontrolButtonGroupSelectionChanged, true);
            app.joycontrolButtonGroup.Title = 'joy control';
            app.NoneButton = uitogglebutton(app.joycontrolButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.Robot1Button_2 = uitogglebutton(app.joycontrolButtonGroup);
            app.Robot1Button_2.Text = 'Robot 1';
            app.Robot2Button_2 = uitogglebutton(app.joycontrolButtonGroup);
            app.Robot2Button_2.Text = 'Robot 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotbartenderAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end